classdef mqAppTabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CollectionMethodTab             matlab.ui.container.Tab
        methodDropDown                  matlab.ui.control.DropDown
        setupLabel_4                    matlab.ui.control.Label
        SelectarootdirectoryfolderforwhichyourdataresidesLabel_3  matlab.ui.control.Label
        nextButtonColMethod             matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        InputMethodTab                  matlab.ui.container.Tab
        setupHomeLabel                  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RootfolderselectionButton       matlab.ui.control.RadioButton
        MultitrialselectionButton       matlab.ui.control.RadioButton
        IndividualtrialselectionButton  matlab.ui.control.RadioButton
        SelectanentiredirectorytoanalyseLabel  matlab.ui.control.Label
        SelectindividualtrialandcalibrationfilestoanalyseLabel  matlab.ui.control.Label
        SelectindividualfileswithinadirectorytoanalyseLabel  matlab.ui.control.Label
        setupLabel_3                    matlab.ui.control.Label
        setupMenuButton_5               matlab.ui.control.Button
        backButtonSelection             matlab.ui.control.Button
        SetupFileSelection              matlab.ui.container.Tab
        setupLabel                      matlab.ui.control.Label
        calibMagDataLabel               matlab.ui.control.Label
        magSelectButton                 matlab.ui.control.Button
        magCalibTextArea                matlab.ui.control.TextArea
        calibStaticDataLabel            matlab.ui.control.Label
        calibStaticButton               matlab.ui.control.Button
        calibStaticTextArea             matlab.ui.control.TextArea
        trialDataLabel                  matlab.ui.control.Label
        trialButton                     matlab.ui.control.Button
        trialTextArea                   matlab.ui.control.TextArea
        setupMenuButton_6               matlab.ui.control.Button
        SetupFolderSelection            matlab.ui.container.Tab
        setupLabel_2                    matlab.ui.control.Label
        trialDataLabel_2                matlab.ui.control.Label
        rootDirectoryButton             matlab.ui.control.Button
        rootDirectoryDisplayPath        matlab.ui.control.TextArea
        setupMenuButton_9               matlab.ui.control.Button
        SetupRootFileSelectionTab       matlab.ui.container.Tab
        trialDataLabel_3                matlab.ui.control.Label
        rootDirectoryButtonIndividual   matlab.ui.control.Button
        rootDirectoryDisplayPathIndividual  matlab.ui.control.TextArea
        trialDataLabel_4                matlab.ui.control.Label
        trialButtonIndividual           matlab.ui.control.Button
        trialIndividualTextArea         matlab.ui.control.TextArea
        setupLabel_5                    matlab.ui.control.Label
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        participantId                   matlab.ui.control.EditField
        DateLabel                       matlab.ui.control.Label
        dateId                          matlab.ui.control.EditField
        SensorTab                       matlab.ui.container.Tab
        viconSensorsLabel               matlab.ui.control.Label
        viconBaseDropDown               matlab.ui.control.DropDown
        viconPelvisDropDown             matlab.ui.control.DropDown
        viconThighLDropDown             matlab.ui.control.DropDown
        viconThighRDropDown             matlab.ui.control.DropDown
        viconFootLDropDown              matlab.ui.control.DropDown
        viconFootRDropDown              matlab.ui.control.DropDown
        viconShankLDropDown             matlab.ui.control.DropDown
        viconShankRDropDown             matlab.ui.control.DropDown
        viconBaseLabel                  matlab.ui.control.Label
        viconPelvisLabel                matlab.ui.control.Label
        viconThighLLabel                matlab.ui.control.Label
        viconThighRLabel                matlab.ui.control.Label
        viconShankRLabel                matlab.ui.control.Label
        viconShankLLabel                matlab.ui.control.Label
        viconFootRLabel                 matlab.ui.control.Label
        viconFootLLabel                 matlab.ui.control.Label
        imuThighRDropDown               matlab.ui.control.DropDown
        imuBaseDropDown                 matlab.ui.control.DropDown
        imuThighLDropDown               matlab.ui.control.DropDown
        imuFootLDropDown                matlab.ui.control.DropDown
        imuFootRDropDown                matlab.ui.control.DropDown
        imuShankLDropDown               matlab.ui.control.DropDown
        imuShankRDropDown               matlab.ui.control.DropDown
        imuPelvisDropDown               matlab.ui.control.DropDown
        imuSensorsLabel                 matlab.ui.control.Label
        imuBaseLabel                    matlab.ui.control.Label
        imuPelvisLabel                  matlab.ui.control.Label
        imuThighLLabel                  matlab.ui.control.Label
        imuThighRLabel                  matlab.ui.control.Label
        imuFootLLabel                   matlab.ui.control.Label
        imuFootRLabel                   matlab.ui.control.Label
        imuShankLLabel                  matlab.ui.control.Label
        imuShankRLabel                  matlab.ui.control.Label
        sensorMainLabel                 matlab.ui.control.Label
        rotationDropDown                matlab.ui.control.DropDown
        rotationLabel                   matlab.ui.control.Label
        TrialTab                        matlab.ui.container.Tab
        trialDurationLabel              matlab.ui.control.Label
        trialDurationRadio              matlab.ui.container.ButtonGroup
        trialEntiretrialButton          matlab.ui.control.RadioButton
        trialSelectStartEndButton       matlab.ui.control.RadioButton
        trialStartTimeHH                matlab.ui.control.NumericEditField
        trialStarttimeLabelTrial        matlab.ui.control.Label
        trialHHLabel                    matlab.ui.control.Label
        trialStartTimeMM                matlab.ui.control.NumericEditField
        trialMMLabel                    matlab.ui.control.Label
        trialStartTimeSS                matlab.ui.control.NumericEditField
        trialSSLabel                    matlab.ui.control.Label
        trialStartTimess                matlab.ui.control.NumericEditField
        trialSsLabel                    matlab.ui.control.Label
        trialEndTimeHH                  matlab.ui.control.NumericEditField
        trialEndTimeMM                  matlab.ui.control.NumericEditField
        trialEndTimeSS                  matlab.ui.control.NumericEditField
        trialEndTimess                  matlab.ui.control.NumericEditField
        trialEndtimeLabel               matlab.ui.control.Label
        trialRateLabel                  matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        trialFreqInText                 matlab.ui.control.EditField
        trialFreqOutText                matlab.ui.control.EditField
        OutputLabel                     matlab.ui.control.Label
        trialLabel                      matlab.ui.control.Label
        trialDurationUnits              matlab.ui.control.DropDown
        trialStartFrameText             matlab.ui.control.NumericEditField
        trialEndFrameText               matlab.ui.control.NumericEditField
        defaultinputoutput1125HzLabel   matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        calibLabel                      matlab.ui.control.Label
        calibRateLabel                  matlab.ui.control.Label
        InputLabel_2                    matlab.ui.control.Label
        calibFreqInText                 matlab.ui.control.EditField
        calibFreqOutText                matlab.ui.control.EditField
        OutputLabel_2                   matlab.ui.control.Label
        calibDurationLabel              matlab.ui.control.Label
        calibDurationRadio              matlab.ui.container.ButtonGroup
        calibEntireRadioButton          matlab.ui.control.RadioButton
        calibSelectRadioButton          matlab.ui.control.RadioButton
        defaultfirst1sLabel             matlab.ui.control.Label
        calibStartLabel                 matlab.ui.control.Label
        calibEndLabel                   matlab.ui.control.Label
        calibEndTimeHH                  matlab.ui.control.NumericEditField
        calibEndTimeMM                  matlab.ui.control.NumericEditField
        calibEndTimeSS                  matlab.ui.control.NumericEditField
        calibEndTimess                  matlab.ui.control.NumericEditField
        calibStartTimeHH                matlab.ui.control.NumericEditField
        calibHHLabel                    matlab.ui.control.Label
        calibStartTimeMM                matlab.ui.control.NumericEditField
        calibMMLabel                    matlab.ui.control.Label
        calibStartTimeSS                matlab.ui.control.NumericEditField
        calibSSLabel                    matlab.ui.control.Label
        calibStartTimess                matlab.ui.control.NumericEditField
        calibSsLabel                    matlab.ui.control.Label
        calibDurationUnits              matlab.ui.control.DropDown
        calibStartFrameText             matlab.ui.control.NumericEditField
        calibEndFrameText               matlab.ui.control.NumericEditField
        defaultinputoutput1125HzLabel_2  matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        tableOutputLabel                matlab.ui.control.Label
        plotOutputLabel                 matlab.ui.control.Label
        ankleBilateralCheck             matlab.ui.control.CheckBox
        allUnilateralRCheck             matlab.ui.control.CheckBox
        allUnilateralLCheck             matlab.ui.control.CheckBox
        allBilateralCheck               matlab.ui.control.CheckBox
        hipUnilateralLCheck             matlab.ui.control.CheckBox
        hipBilateralCheck               matlab.ui.control.CheckBox
        ankleUnilateralRCheck           matlab.ui.control.CheckBox
        ankleUnilateralLCheck           matlab.ui.control.CheckBox
        kneeUnilateralRCheck            matlab.ui.control.CheckBox
        kneeUnilateralLCheck            matlab.ui.control.CheckBox
        kneeBilateralCheck              matlab.ui.control.CheckBox
        hipUnilateralRCheck             matlab.ui.control.CheckBox
        dataOutputDirectoryLabel        matlab.ui.control.Label
        dataOutputButton                matlab.ui.control.Button
        dataOutputDirectoryField        matlab.ui.control.EditField
        plotOutputButton                matlab.ui.control.Button
        plotOutputDirectoryField        matlab.ui.control.EditField
        plotOutputDirectoryLabel        matlab.ui.control.Label
        hipRDataCheck                   matlab.ui.control.CheckBox
        hipLDataCheck                   matlab.ui.control.CheckBox
        timeDataCheck                   matlab.ui.control.CheckBox
        allDataCheck                    matlab.ui.control.CheckBox
        ankleRDataCheck                 matlab.ui.control.CheckBox
        ankleLDataCheck                 matlab.ui.control.CheckBox
        kneeRDataCheck                  matlab.ui.control.CheckBox
        kneeLDataCheck                  matlab.ui.control.CheckBox
        exportLabel                     matlab.ui.control.Label
        analysisLabel                   matlab.ui.control.Label
        sagittalCheck                   matlab.ui.control.CheckBox
        allPlaneCheck                   matlab.ui.control.CheckBox
        transverseCheck                 matlab.ui.control.CheckBox
        frontalCheck                    matlab.ui.control.CheckBox
        FilteringTab                    matlab.ui.container.Tab
        filterLabel                     matlab.ui.control.Label
        filterPropsLabel                matlab.ui.control.Label
        FiltertypeIIRLabel              matlab.ui.control.Label
        filterTypeDropDown              matlab.ui.control.DropDown
        filterPassbandFreqVal           matlab.ui.control.EditField
        filterPassbandRippleVal         matlab.ui.control.EditField
        filterHalfPowFreq2Val           matlab.ui.control.EditField
        filterHalfPowFreq1Val           matlab.ui.control.EditField
        filterPassbandFreqLab           matlab.ui.control.Label
        filterHalfPowFreq1Lab           matlab.ui.control.Label
        filterHalfPowFreq2Lab           matlab.ui.control.Label
        filterPassbandRippleLab         matlab.ui.control.Label
        SelectfiltertypeandfiltefileLabel  matlab.ui.control.Label
        filterSelectField               matlab.ui.control.TextArea
        filterSelectButton              matlab.ui.control.Button
        filterOrderVal                  matlab.ui.control.EditField
        filterOrderLab                  matlab.ui.control.Label
        filterClearButton               matlab.ui.control.Button
        filterTypeRadioGroup            matlab.ui.container.ButtonGroup
        FIRButton                       matlab.ui.control.RadioButton
        IIRButton                       matlab.ui.control.RadioButton
        SecondorderIIRButton            matlab.ui.control.RadioButton
        filterPassbandDescText          matlab.ui.control.Label
        filterPassbandDescLab           matlab.ui.control.Label
        filterRippleDescText            matlab.ui.control.Label
        filterRippleDescLab             matlab.ui.control.Label
        filterHalfpower1DescText        matlab.ui.control.Label
        filterHalfpower1DescLab         matlab.ui.control.Label
        filterHalfpower2DescText        matlab.ui.control.Label
        filterHalfpower2DescLab         matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        runButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        runLabel                        matlab.ui.control.Label
        completionLabel                 matlab.ui.control.Label
        percentageCompleteLabel         matlab.ui.control.Label
        continueButton                  matlab.ui.control.Button
        PlotTabGroup                    matlab.ui.container.TabGroup
        ManualPlots                     matlab.ui.container.Tab
        StaticAxes                      matlab.ui.control.UIAxes
        MotionAxes                      matlab.ui.control.UIAxes
        TrialAxes2                      matlab.ui.control.UIAxes
        AutoPlots                       matlab.ui.container.Tab
        TrialAxes                       matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        UserguideTab                    matlab.ui.container.Tab
        userLabel                       matlab.ui.control.Label
        FilenamingDescriptionLabel      matlab.ui.control.Label
        TrialdataLabel                  matlab.ui.control.Label
        StaticcalibrationdataLabel      matlab.ui.control.Label
        MagneticcalibrationdataLabel    matlab.ui.control.Label
        exampleDescriptionLabel         matlab.ui.control.Label
        exampleDescriptionLabelTwo      matlab.ui.control.Label
        mag_participant_datecsvLabel    matlab.ui.control.Label
        static_participant_datecsvLabel  matlab.ui.control.Label
        exercise_participant_trial_datecsvLabel  matlab.ui.control.Label
        ExamplesLabel                   matlab.ui.control.Label
        viconNamingButton               matlab.ui.control.Button
        namingConvButton                matlab.ui.control.Button
        setupMenuButton_4               matlab.ui.control.Button
        ViconNameDescription            matlab.ui.control.Label
        viconNamesA                     matlab.ui.control.Image
        viconNamesB                     matlab.ui.control.Image
        sensorDescriptionLabel          matlab.ui.control.Label
        sensorLocationImage             matlab.ui.control.Image
        sensorLocations                 matlab.ui.control.Label
        sensorDescriptionLabel2         matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        settingsLabel                   matlab.ui.control.Label
        settingsAutoLabel               matlab.ui.control.Label
        settingsAutoRadio               matlab.ui.container.ButtonGroup
        settingsAuto                    matlab.ui.control.RadioButton
        settingsManual                  matlab.ui.control.RadioButton
        settingsAutoDescription         matlab.ui.control.Label
        diagnosticPlotLabel             matlab.ui.control.Label
        diagnosticPlotDescription       matlab.ui.control.Label
        SaveDiagnosticPlotCheckBox      matlab.ui.control.CheckBox
        SideMenuButtonGroup             matlab.ui.container.ButtonGroup
        setupMenuButton                 matlab.ui.control.Button
        trialMenuButton                 matlab.ui.control.Button
        userGuideMenuButton             matlab.ui.control.Button
        runMenuButton                   matlab.ui.control.Button
        exportMenuButton                matlab.ui.control.Button
        calibrationMenuButton           matlab.ui.control.Button
        sensorsMenuButton               matlab.ui.control.Button
        settingsButton                  matlab.ui.control.Button
        filterMenuButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        dir = addpath("funcs/");
        pa0 = addpath("AppFunctions/");
        pa1 = addpath("AppFunctions/calibration/");
        pa2 = addpath("AppFunctions/export/");
        pa3 = addpath("AppFunctions/export/sensorSelectionChange/");
        pa4 = addpath("AppFunctions/filter/");
        pa5 = addpath("AppFunctions/run/");
        pa6 = addpath("AppFunctions/sensors/");
        pa7 = addpath("AppFunctions/setup/");
        pa8 = addpath("AppFunctions/system/");
        pa9 = addpath("AppFunctions/trial/");
        pa10 = addpath("AppFunctions/uiGuide/");

        outputJointAngle
        outputTime
        outputFrame
        planes
        
        rootDirectoryPath
        
        %[CalFileName, CalFilePath,~]
        calibStatFiles
        calibStatPaths
        calibStatFile
        calibStatPath
        
        tempCalibStatFile
        tempCalibStatPath
        
        calibMagFiles = ""
        calibMagPaths = ""
        calibMagFile
        calibMagPath
        
        trialFiles = ""
        trialPaths = ""
        trialFile = ""
        trialPath = ""
        tempTrialFile
                
        datafilepath        
        dataOutPath
        plotOutPath
        plotfiletitle 
        plotJoint
        
        filterFiles
        filterFile
        filterPaths
        filterPath
        
        rotationSequence = "YXZ"
        
        trialNames
        calibStatNames
        calibMagNames
        isVicon = true
        dropdowns
        sensorIds = ["none"]

        trialRateIn = 1125
        trialRateOut = 1125
        calibRateIn = 1125
        calibRateOut = 1125

        trialStartTimeSec = 0
        trialEndTimeSec = 0
        calibStartTimeSec = 0
        calibEndTimeSec = 0

        trialStartFrame = 1
        trialEndFrame = 2
        
        calibStaticStartFrame = 0
        calibStaticEndFrame = 0
        
        tableData 
        
        joints = []
        legendArray = []
        magCalTable
        debugFigures = []
        mainFigures = []
        dataExportTable
        
        debugTitle
        
        continueStatus = 1

    end
    
    methods (Access = private)
        function setupRunView(app)
            app.UIAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.UIAxes)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup(app)
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            if app.RootfolderselectionButton.Value == 1 
                runBatchRootDirectory(app);
            elseif app.IndividualtrialselectionButton.Value == 1
                    runBatchRootDirectoryIndividual(app);
            else
                runBatchFileSelection(app);
            end
        end

        % Button pushed function: setupMenuButton
        function setupMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SetupTab;
        end

        % Callback function
        function filteringMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FilteringTab;
        end

        % Button pushed function: trialMenuButton
        function trialMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TrialTab;
        end

        % Button pushed function: userGuideMenuButton
        function userGuideMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.UserguideTab;
        end

        % Button pushed function: runMenuButton
        function runMenuButtonPushed(app, event)
            setupRunView(app);
            app.TabGroup.SelectedTab = app.RunTab;
        end

        % Button pushed function: exportMenuButton
        function exportMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ExportTab;
        end

        % Button pushed function: trialButton
        function trialButtonPushed(app, event)
            trialButtonPush(app,event)
        end

        % Button pushed function: calibStaticButton
        function calibStatButtonPushed(app, event)
            calibStatButtonPush(app,event);
        end

        % Button pushed function: calibrationMenuButton
        function calibStatMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CalibrationTab;
        end

        % Selection changed function: calibDurationRadio
        function calibDurationRadioSelectionChanged(app, event)
            calibrationButtonChange(app);
        end

        % Selection changed function: trialDurationRadio
        function trialDurationRadioSelectionChanged(app, event)
            trialButtonChange(app);
        end

        % Value changed function: allBilateralCheck
        function allBilateralCheckValueChanged(app, event)
            bilateralCheckChange(app);
        end

        % Value changed function: allUnilateralRCheck
        function allUnilateralRCheckValueChanged(app, event)
            unilateralRCheckChange(app);
        end

        % Value changed function: allUnilateralLCheck
        function allUnilateralLCheckValueChanged(app, event)
            unilateralLCheckChange(app);
        end

        % Value changed function: ankleBilateralCheck
        function ankleBilateralCheckValueChanged(app, event)
            ankleBilateralCheckChange(app);
        end

        % Value changed function: hipBilateralCheck
        function hipBilateralCheckValueChanged(app, event)
            hipBilateralCheckChange(app);
        end

        % Value changed function: kneeBilateralCheck
        function kneeBilateralCheckValueChanged(app, event)
            kneeBilateralCheckChange(app);
        end

        % Value changed function: allDataCheck
        function allDataCheckValueChanged(app, event)
            allDataCheckChange(app);
        end

        % Button pushed function: dataOutputButton
        function dataOutputButtonPushed(app, event)
            dataOutputButtonPush(app,event);
        end

        % Button pushed function: plotOutputButton
        function plotOutputButtonPushed(app, event)
            plotOutputButtonPush(app,event);
        end

        % Value changed function: methodDropDown
        function methodDropDownValueChanged(app, event)
            methodDropDownChange(app,event);
        end

        % Button pushed function: sensorsMenuButton
        function sensorsMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SensorTab;
        end

        % Button pushed function: magSelectButton
        function magSelectButtonPushed(app, event)
            magSelectButtonPush(app, event)
        end

        % Value changed function: trialFreqInText
        function trialFreqInTextfieldChanged(app, event)
            trialFreqInTextfieldChange(app,event);
        end

        % Value changed function: trialFreqOutText
        function trailFreqOutTextfieldChanged(app, event)
            value = app.trialFreqOutText.Value;
            app.trialRateOut = value;
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialStartTimeHH
        function trialStartTimeHHChanged(app, event)
            value = app.trialStartTimeHH.Value;
            app.trialStartTimeSec = app.trialStartTimeSec + (value * 3600);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialStartTimeMM
        function trialStartTimeMMChanged(app, event)
            value = app.trialStartTimeMM.Value;
            app.trialStartTimeSec = app.trialStartTimeSec + (value * 60);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialStartTimeSS
        function trialStartTimeSSChanged(app, event)
            value = app.trialStartTimeSS.Value;
            app.trialStartTimeSec = app.trialStartTimeSec + value;
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialStartTimess
        function trialStartTimessChanged(app, event)
            value = app.trialStartTimess.Value;
            app.trialStartTimeSec = app.trialStartTimeSec + (value * 0.1);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialEndTimeHH
        function trialEndTimeHHChanged(app, event)
            value = app.trialEndTimeHH.Value;
            app.trialEndTimeSec = app.trialEndTimeSec + (value * 3600);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialEndTimeMM
        function trialEndTimeMMChanged(app, event)
            value = app.trialEndTimeMM.Value;
            app.trialEndTimeSec = app.trialEndTimeSec + (value * 60);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialEndTimeSS
        function trialEndTimeSSChanged(app, event)
            value = app.trialEndTimeSS.Value;
            app.trialEndTimeSec = app.trialEndTimeSec + value;
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialEndTimess
        function trialEndTimessChanged(app, event)
            value = app.trialEndTimess.Value;
            app.trialEndTimeSec = app.trialEndTimeSec + (value * 0.1);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialDurationUnits
        function trialDurationUnitsChanged(app, event)
            trialDurationUnitsChange(app,event);
        end

        % Value changed function: trialStartFrameText
        function trialStartFrameTextChanged(app, event)
            value = app.trialStartFrameText.Value;
            app.trialStartFrame = value;
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: trialEndFrameText
        function trialEndFrameTextChanged(app, event)
            value = app.trialEndFrameText.Value;
            app.trialEndFrame = value;
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: calibFreqInText
        function calibFreqInTextChanged(app, event)
            value = app.calibFreqInText.Value;
            app.calibRateIn = str2double(value);
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: calibFreqOutText
        function calibFreqOutTextChanged(app, event)
            value = app.calibFreqOutText.Value;
            app.calibRateOut = value;
            trialUpdateStartEndFrame(app);
        end

        % Value changed function: calibDurationUnits
        function calibDurationUnitsChanged(app, event)
            calibDurationUnitsChange(app,event);
        end

        % Value changed function: calibEndFrameText
        function calibEndFrameameTextChanged(app, event)
            value = app.calibEndFrameText.Value;
            app.calibStaticEndFrame = value;
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibStartFrameText
        function calibStartFrameTextChanged(app, event)
            value = app.calibStartFrameText.Value;
            app.calibStaticStartFrame = value;
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibStartTimeHH
        function calibStartTimeHHChanged(app, event)
            value = app.calibStartTimeHH.Value;
            app.calibStartTimeSec = app.calibStartTimeSec + (value * 3600);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibStartTimeMM
        function calibStartTimeMMChanged(app, event)
            value = app.calibStartTimeMM.Value;
            app.calibStartTimeSec = app.calibStartTimeSec + (60 * value);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibStartTimeSS
        function calibStartTimeSSChanged(app, event)
            value = app.calibStartTimeSS.Value;
            app.calibStartTimeSec = app.calibStartTimeSec + value;
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibStartTimess
        function calibStartTimeChanged(app, event)
            value = app.calibStartTimess.Value;
            app.calibStartTimeSec = app.calibStartTimeSec + (value * 0.1);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibEndTimeHH
        function calibEndTimeHHChanged(app, event)
            value = app.calibEndTimeHH.Value;
            app.calibEndTimeSec = app.calibEndTimeSec + (value * 3600);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibEndTimeMM
        function calibEndTimeMMChanged(app, event)
            value = app.calibEndTimeMM.Value;
            app.calibEndTimeSec = app.calibEndTimeSec + (value * 60);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibEndTimeSS
        function calibEndTimeSSChanged(app, event)
            value = app.calibEndTimeSS.Value;
            app.calibEndTimeSec = app.calibEndTimeSec + (value);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: calibEndTimess
        function calibEndTimeChanged(app, event)
            value = app.calibEndTimess.Value;
            app.calibEndTimeSec = app.calibEndTimeSec + (value * 0.1);
            calibUpdateStartEndFrame(app)
        end

        % Value changed function: rotationDropDown
        function rotationDropDownValueChanged(app, event)
            value = app.rotationDropDown.Value;
            app.rotationSequence = value;
        end

        % Value changed function: allPlaneCheck
        function allPlaneCheckValueChanged(app, event)
            allPlaneCheckChange(app,event);
        end

        % Button pushed function: namingConvButton
        function namingConvButtonPushed(app, event)
%             app.displayViconNaming(0)
%             app.displaySensorLocation(0)
%             app.displayUserGuideNaming(1)
        end

        % Button pushed function: viconNamingButton
        function viconNamingButtonPushed(app, event)
%             app.displayUserGuideNaming(0)
%             app.displaySensorLocation(0)
%             app.displayViconNaming(1)
        end

        % Button pushed function: setupMenuButton_4
        function setupMenuButton_4Pushed(app, event)
%             app.displayViconNaming(0)
%             app.displayUserGuideNaming(0)
%             app.displaySensorLocation(1)
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
        end

        % Selection changed function: settingsAutoRadio
        function settingsAutoRadioSelectionChanged(app, event)
            selectedButton = app.settingsAutoRadio.SelectedObject;
            if selectedButton == "Auto"
%                 app.continueButton.Visible = 0;
            else
%                 app.continueButton.Visible = 1;
            end
        end

        % Button pushed function: continueButton
        function continueButtonPushed(app, event)
            app.continueStatus = 1;
            app.continueButton.Visible = 0;
        end

        % Button pushed function: nextButtonColMethod
        function nextButtonColMethodPushed(app, event)
            app.TabGroup2.SelectedTab = app.InputMethodTab;
        end

        % Button pushed function: backButtonSelection
        function backButtonSelectionPushed(app, event)
            app.TabGroup2.SelectedTab = app.CollectionMethodTab;
        end

        % Button pushed function: setupMenuButton_5
        function setupMenuButton_5Pushed(app, event)
            if app.RootfolderselectionButton.Value == 1 
                app.TabGroup2.SelectedTab = app.SetupFolderSelection;
                
            elseif app.IndividualtrialselectionButton.Value == 1
                app.TabGroup2.SelectedTab  = app.SetupRootFileSelectionTab;
                
            else
                app.TabGroup2.SelectedTab = app.SetupFileSelection;
            end
        end

        % Button pushed function: setupMenuButton_6
        function setupMenuButton_6Pushed(app, event)
            app.TabGroup2.SelectedTab = app.InputMethodTab;
        end

        % Button pushed function: setupMenuButton_9
        function setupMenuButton_9Pushed(app, event)
            app.TabGroup2.SelectedTab = app.InputMethodTab;
        end

        % Button pushed function: rootDirectoryButton
        function rootDirectoryButtonPushed(app, event)
            rootDirectoryButtonPush(app,event);
        end

        % Button pushed function: filterMenuButton
        function filterMenuButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FilteringTab;
        end

        % Value changed function: filterTypeDropDown
        function filterTypeDropDownValueChanged(app, event)
            filterTypeDropDownChange(app,event);
        end

        % Button pushed function: filterSelectButton
        function filterSelectButtonPushed(app, event)
            filterSelectButtonPush(app,event);
        end

        % Button pushed function: filterClearButton
        function filterClearButtonPushed(app, event)
            app.filterPath = "";
            app.filterFile = "";
            app.filterSelectField = "";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closeApp(app,event)
        end

        % Button pushed function: rootDirectoryButtonIndividual
        function rootDirectoryButtonIndividualPushed(app, event)
            rootDirectoryIndividualButtonPush(app,event);
        end

        % Button pushed function: trialButtonIndividual
        function trialButtonIndividualPushed(app, event)
            trialIndividualButtonPush(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TabGroup2 = uitabgroup(app.SetupTab);
            app.CollectionMethodTab = uitab(app.TabGroup2);
            app.CollectionMethodTab.Title = 'Tab4';
            app.methodDropDown = uidropdown(app.CollectionMethodTab);
            app.methodDropDown.Items = {'Vicon', 'IMeasureU'};
            app.methodDropDown.ValueChangedFcn = createCallbackFcn(app, @methodDropDownValueChanged, true);
            app.methodDropDown.Value = 'Vicon';
            app.setupLabel_4 = uilabel(app.CollectionMethodTab);
            app.setupLabel_4.Text = 'Setup - Collection method';
            app.SelectarootdirectoryfolderforwhichyourdataresidesLabel_3 = uilabel(app.CollectionMethodTab);
            app.SelectarootdirectoryfolderforwhichyourdataresidesLabel_3.Text = '';
            app.nextButtonColMethod = uibutton(app.CollectionMethodTab, 'push');
            app.nextButtonColMethod.ButtonPushedFcn = createCallbackFcn(app, @nextButtonColMethodPushed, true);
            app.nextButtonColMethod.Text = 'Next ->';
            app.Label_2 = uilabel(app.CollectionMethodTab);
            app.Label_2.Text = {'Select the method used to collect IMU data.'; ''; 'Vicon - Data collected using a PC and the Vicon Nexus suite. Data output from Vicon Nexus to .csv'; 'format.'; ''; 'IMU - Data collected using a tablet and the IMeasureU app. Data output from the app to .csv.'};
            app.InputMethodTab = uitab(app.TabGroup2);
            app.InputMethodTab.Title = 'Tab3';
            app.setupHomeLabel = uilabel(app.InputMethodTab);
            app.setupHomeLabel.Text = 'Setup';
            app.ButtonGroup = uibuttongroup(app.InputMethodTab);
            app.RootfolderselectionButton = uiradiobutton(app.ButtonGroup);
            app.RootfolderselectionButton.Text = 'Root folder selection';
            app.RootfolderselectionButton.Value = true;
            app.MultitrialselectionButton = uiradiobutton(app.ButtonGroup);
            app.MultitrialselectionButton.Text = 'Multi-trial selection';
            app.IndividualtrialselectionButton = uiradiobutton(app.ButtonGroup);
            app.IndividualtrialselectionButton.Text = 'Individual trial selection';
            app.SelectanentiredirectorytoanalyseLabel = uilabel(app.ButtonGroup);
            app.SelectanentiredirectorytoanalyseLabel.Text = 'Select an entire directory to analyse.';
            app.SelectindividualtrialandcalibrationfilestoanalyseLabel = uilabel(app.ButtonGroup);
            app.SelectindividualtrialandcalibrationfilestoanalyseLabel.Text = 'Select individual, trial and calibration files to analyse.';
            app.SelectindividualfileswithinadirectorytoanalyseLabel = uilabel(app.ButtonGroup);
            app.SelectindividualfileswithinadirectorytoanalyseLabel.Text = 'Select individual files within a directory to analyse.';
            app.setupLabel_3 = uilabel(app.InputMethodTab);
            app.setupLabel_3.Text = 'Setup - by file selection';
            app.setupMenuButton_5 = uibutton(app.InputMethodTab, 'push');
            app.setupMenuButton_5.ButtonPushedFcn = createCallbackFcn(app, @setupMenuButton_5Pushed, true);
            app.setupMenuButton_5.Text = 'Next ->';
            app.backButtonSelection = uibutton(app.InputMethodTab, 'push');
            app.backButtonSelection.ButtonPushedFcn = createCallbackFcn(app, @backButtonSelectionPushed, true);
            app.backButtonSelection.Text = '<- Back';
            app.SetupFileSelection = uitab(app.TabGroup2);
            app.SetupFileSelection.Title = 'Tab';
            app.setupLabel = uilabel(app.SetupFileSelection);
            app.setupLabel.Text = 'Setup - by file selection';
            app.calibMagDataLabel = uilabel(app.SetupFileSelection);
            app.calibMagDataLabel.Text = 'Magnetic calibration data (.csv)';
            app.magSelectButton = uibutton(app.SetupFileSelection, 'push');
            app.magSelectButton.ButtonPushedFcn = createCallbackFcn(app, @magSelectButtonPushed, true);
            app.magSelectButton.Text = 'Select files';
            app.magCalibTextArea = uitextarea(app.SetupFileSelection);
            app.magCalibTextArea.Editable = 'off';
            app.calibStaticDataLabel = uilabel(app.SetupFileSelection);
            app.calibStaticDataLabel.Text = 'Static calibration data (.csv)';
            app.calibStaticButton = uibutton(app.SetupFileSelection, 'push');
            app.calibStaticButton.ButtonPushedFcn = createCallbackFcn(app, @calibStatButtonPushed, true);
            app.calibStaticButton.Text = 'Select files';
            app.calibStaticTextArea = uitextarea(app.SetupFileSelection);
            app.calibStaticTextArea.Editable = 'off';
            app.trialDataLabel = uilabel(app.SetupFileSelection);
            app.trialDataLabel.Text = 'Trial data (.csv)';
            app.trialButton = uibutton(app.SetupFileSelection, 'push');
            app.trialButton.ButtonPushedFcn = createCallbackFcn(app, @trialButtonPushed, true);
            app.trialButton.Text = 'Select files';
            app.trialTextArea = uitextarea(app.SetupFileSelection);
            app.trialTextArea.Editable = 'off';
            app.setupMenuButton_6 = uibutton(app.SetupFileSelection, 'push');
            app.setupMenuButton_6.ButtonPushedFcn = createCallbackFcn(app, @setupMenuButton_6Pushed, true);
            app.setupMenuButton_6.Text = '<- Back';
            app.SetupFolderSelection = uitab(app.TabGroup2);
            app.SetupFolderSelection.Title = 'Tab2';
            app.setupLabel_2 = uilabel(app.SetupFolderSelection);
            app.setupLabel_2.Text = 'Setup - by root directory selection';
            app.trialDataLabel_2 = uilabel(app.SetupFolderSelection);
            app.trialDataLabel_2.Text = 'Root directory';
            app.rootDirectoryButton = uibutton(app.SetupFolderSelection, 'push');
            app.rootDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @rootDirectoryButtonPushed, true);
            app.rootDirectoryButton.Text = 'Select folder';
            app.rootDirectoryDisplayPath = uitextarea(app.SetupFolderSelection);
            app.rootDirectoryDisplayPath.Editable = 'off';
            app.setupMenuButton_9 = uibutton(app.SetupFolderSelection, 'push');
            app.setupMenuButton_9.ButtonPushedFcn = createCallbackFcn(app, @setupMenuButton_9Pushed, true);
            app.setupMenuButton_9.Text = '<- Back';
            app.SetupRootFileSelectionTab = uitab(app.TabGroup2);
            app.SetupRootFileSelectionTab.Title = 'SetupRootFileSelection';
            app.trialDataLabel_3 = uilabel(app.SetupRootFileSelectionTab);
            app.trialDataLabel_3.Text = 'Root directory';
            app.rootDirectoryButtonIndividual = uibutton(app.SetupRootFileSelectionTab, 'push');
            app.rootDirectoryButtonIndividual.ButtonPushedFcn = createCallbackFcn(app, @rootDirectoryButtonIndividualPushed, true);
            app.rootDirectoryButtonIndividual.Text = 'Select folder';
            app.rootDirectoryDisplayPathIndividual = uitextarea(app.SetupRootFileSelectionTab);
            app.rootDirectoryDisplayPathIndividual.Editable = 'off';
            app.trialDataLabel_4 = uilabel(app.SetupRootFileSelectionTab);
            app.trialDataLabel_4.Text = 'Trial data (.csv)';
            app.trialButtonIndividual = uibutton(app.SetupRootFileSelectionTab, 'push');
            app.trialButtonIndividual.ButtonPushedFcn = createCallbackFcn(app, @trialButtonIndividualPushed, true);
            app.trialButtonIndividual.Text = 'Select files';
            app.trialIndividualTextArea = uitextarea(app.SetupRootFileSelectionTab);
            app.trialIndividualTextArea.Editable = 'off';
            app.setupLabel_5 = uilabel(app.SetupRootFileSelectionTab);
            app.setupLabel_5.Text = 'Setup - by root & file selection';
            app.ParticipantIDEditFieldLabel = uilabel(app.SetupRootFileSelectionTab);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.participantId = uieditfield(app.SetupRootFileSelectionTab, 'text');
            app.DateLabel = uilabel(app.SetupRootFileSelectionTab);
            app.DateLabel.Text = 'Date';
            app.dateId = uieditfield(app.SetupRootFileSelectionTab, 'text');
            app.SensorTab = uitab(app.TabGroup);
            app.SensorTab.Title = 'Sensor';
            app.viconSensorsLabel = uilabel(app.SensorTab);
            app.viconSensorsLabel.Text = 'Assign sensors';
            app.viconBaseDropDown = uidropdown(app.SensorTab);
            app.viconBaseDropDown.Items = {'none'};
            app.viconBaseDropDown.Enable = 'off';
            app.viconBaseDropDown.Value = 'none';
            app.viconPelvisDropDown = uidropdown(app.SensorTab);
            app.viconPelvisDropDown.Items = {'none'};
            app.viconPelvisDropDown.Enable = 'off';
            app.viconPelvisDropDown.Value = 'none';
            app.viconThighLDropDown = uidropdown(app.SensorTab);
            app.viconThighLDropDown.Items = {'none'};
            app.viconThighLDropDown.Enable = 'off';
            app.viconThighLDropDown.Value = 'none';
            app.viconThighRDropDown = uidropdown(app.SensorTab);
            app.viconThighRDropDown.Items = {'none'};
            app.viconThighRDropDown.Enable = 'off';
            app.viconThighRDropDown.Value = 'none';
            app.viconFootLDropDown = uidropdown(app.SensorTab);
            app.viconFootLDropDown.Items = {'none'};
            app.viconFootLDropDown.Enable = 'off';
            app.viconFootLDropDown.Value = 'none';
            app.viconFootRDropDown = uidropdown(app.SensorTab);
            app.viconFootRDropDown.Items = {'none'};
            app.viconFootRDropDown.Enable = 'off';
            app.viconFootRDropDown.Value = 'none';
            app.viconShankLDropDown = uidropdown(app.SensorTab);
            app.viconShankLDropDown.Items = {'none'};
            app.viconShankLDropDown.Enable = 'off';
            app.viconShankLDropDown.Value = 'none';
            app.viconShankRDropDown = uidropdown(app.SensorTab);
            app.viconShankRDropDown.Items = {'none'};
            app.viconShankRDropDown.Enable = 'off';
            app.viconShankRDropDown.Value = 'none';
            app.viconBaseLabel = uilabel(app.SensorTab);
            app.viconBaseLabel.Text = 'Base';
            app.viconPelvisLabel = uilabel(app.SensorTab);
            app.viconPelvisLabel.Text = 'Pelvis';
            app.viconThighLLabel = uilabel(app.SensorTab);
            app.viconThighLLabel.Text = 'Thigh (L)';
            app.viconThighRLabel = uilabel(app.SensorTab);
            app.viconThighRLabel.Text = 'Thigh (R)';
            app.viconShankRLabel = uilabel(app.SensorTab);
            app.viconShankRLabel.Text = 'Shank (R)';
            app.viconShankLLabel = uilabel(app.SensorTab);
            app.viconShankLLabel.Text = 'Shank (L)';
            app.viconFootRLabel = uilabel(app.SensorTab);
            app.viconFootRLabel.Text = 'Foot (R)';
            app.viconFootLLabel = uilabel(app.SensorTab);
            app.viconFootLLabel.Text = 'Foot (L)';
            app.imuThighRDropDown = uidropdown(app.SensorTab);
            app.imuThighRDropDown.Items = {'none'};
            app.imuThighRDropDown.Enable = 'off';
            app.imuThighRDropDown.Visible = 'off';
            app.imuThighRDropDown.Value = 'none';
            app.imuBaseDropDown = uidropdown(app.SensorTab);
            app.imuBaseDropDown.Items = {'none'};
            app.imuBaseDropDown.Enable = 'off';
            app.imuBaseDropDown.Visible = 'off';
            app.imuBaseDropDown.Value = 'none';
            app.imuThighLDropDown = uidropdown(app.SensorTab);
            app.imuThighLDropDown.Items = {'none'};
            app.imuThighLDropDown.Enable = 'off';
            app.imuThighLDropDown.Visible = 'off';
            app.imuThighLDropDown.Value = 'none';
            app.imuFootLDropDown = uidropdown(app.SensorTab);
            app.imuFootLDropDown.Items = {'none'};
            app.imuFootLDropDown.Enable = 'off';
            app.imuFootLDropDown.Visible = 'off';
            app.imuFootLDropDown.Value = 'none';
            app.imuFootRDropDown = uidropdown(app.SensorTab);
            app.imuFootRDropDown.Items = {'none'};
            app.imuFootRDropDown.Enable = 'off';
            app.imuFootRDropDown.Visible = 'off';
            app.imuFootRDropDown.Value = 'none';
            app.imuShankLDropDown = uidropdown(app.SensorTab);
            app.imuShankLDropDown.Items = {'none'};
            app.imuShankLDropDown.Enable = 'off';
            app.imuShankLDropDown.Visible = 'off';
            app.imuShankLDropDown.Value = 'none';
            app.imuShankRDropDown = uidropdown(app.SensorTab);
            app.imuShankRDropDown.Items = {'none'};
            app.imuShankRDropDown.Enable = 'off';
            app.imuShankRDropDown.Visible = 'off';
            app.imuShankRDropDown.Value = 'none';
            app.imuPelvisDropDown = uidropdown(app.SensorTab);
            app.imuPelvisDropDown.Items = {'none'};
            app.imuPelvisDropDown.Enable = 'off';
            app.imuPelvisDropDown.Visible = 'off';
            app.imuPelvisDropDown.Value = 'none';
            app.imuSensorsLabel = uilabel(app.SensorTab);
            app.imuSensorsLabel.Visible = 'off';
            app.imuSensorsLabel.Text = 'Assign sensors';
            app.imuBaseLabel = uilabel(app.SensorTab);
            app.imuBaseLabel.Visible = 'off';
            app.imuBaseLabel.Text = 'Base';
            app.imuPelvisLabel = uilabel(app.SensorTab);
            app.imuPelvisLabel.Visible = 'off';
            app.imuPelvisLabel.Text = 'Pelvis';
            app.imuThighLLabel = uilabel(app.SensorTab);
            app.imuThighLLabel.Visible = 'off';
            app.imuThighLLabel.Text = 'Thigh (L)';
            app.imuThighRLabel = uilabel(app.SensorTab);
            app.imuThighRLabel.Visible = 'off';
            app.imuThighRLabel.Text = 'Thigh (R)';
            app.imuFootLLabel = uilabel(app.SensorTab);
            app.imuFootLLabel.Visible = 'off';
            app.imuFootLLabel.Text = 'Foot (L)';
            app.imuFootRLabel = uilabel(app.SensorTab);
            app.imuFootRLabel.Visible = 'off';
            app.imuFootRLabel.Text = 'Foot (R)';
            app.imuShankLLabel = uilabel(app.SensorTab);
            app.imuShankLLabel.Visible = 'off';
            app.imuShankLLabel.Text = 'Shank (L)';
            app.imuShankRLabel = uilabel(app.SensorTab);
            app.imuShankRLabel.Visible = 'off';
            app.imuShankRLabel.Text = 'Shank (R)';
            app.sensorMainLabel = uilabel(app.SensorTab);
            app.sensorMainLabel.Text = 'Sensor assignment';
            app.rotationDropDown = uidropdown(app.SensorTab);
            app.rotationDropDown.Items = {'YXZ', 'XYZ', 'XZY', 'XYX', 'XZX', 'YZX', 'YXY', 'YZY', 'ZXY', 'ZYX', 'ZXZ', 'ZYZ'};
            app.rotationDropDown.ValueChangedFcn = createCallbackFcn(app, @rotationDropDownValueChanged, true);
            app.rotationDropDown.Value = 'YXZ';
            app.rotationLabel = uilabel(app.SensorTab);
            app.rotationLabel.Text = 'Rotation sequence';
            app.TrialTab = uitab(app.TabGroup);
            app.TrialTab.Title = 'Trial';
            app.trialDurationLabel = uilabel(app.TrialTab);
            app.trialDurationLabel.Text = 'Trail duration';
            app.trialDurationRadio = uibuttongroup(app.TrialTab);
            app.trialDurationRadio.SelectionChangedFcn = createCallbackFcn(app, @trialDurationRadioSelectionChanged, true);
            app.trialEntiretrialButton = uiradiobutton(app.trialDurationRadio);
            app.trialEntiretrialButton.Text = 'Entire trial';
            app.trialEntiretrialButton.Value = true;
            app.trialSelectStartEndButton = uiradiobutton(app.trialDurationRadio);
            app.trialSelectStartEndButton.Text = 'Select start/end timestamp';
            app.trialStartTimeHH = uieditfield(app.TrialTab, 'numeric');
            app.trialStartTimeHH.Limits = [0 Inf];
            app.trialStartTimeHH.RoundFractionalValues = 'on';
            app.trialStartTimeHH.ValueChangedFcn = createCallbackFcn(app, @trialStartTimeHHChanged, true);
            app.trialStartTimeHH.Enable = 'off';
            app.trialStartTimeHH.Visible = 'off';
            app.trialStarttimeLabelTrial = uilabel(app.TrialTab);
            app.trialStarttimeLabelTrial.Enable = 'off';
            app.trialStarttimeLabelTrial.Text = 'Start frame';
            app.trialHHLabel = uilabel(app.TrialTab);
            app.trialHHLabel.Enable = 'off';
            app.trialHHLabel.Visible = 'off';
            app.trialHHLabel.Text = 'HH';
            app.trialStartTimeMM = uieditfield(app.TrialTab, 'numeric');
            app.trialStartTimeMM.Limits = [0 Inf];
            app.trialStartTimeMM.RoundFractionalValues = 'on';
            app.trialStartTimeMM.ValueChangedFcn = createCallbackFcn(app, @trialStartTimeMMChanged, true);
            app.trialStartTimeMM.Enable = 'off';
            app.trialStartTimeMM.Visible = 'off';
            app.trialMMLabel = uilabel(app.TrialTab);
            app.trialMMLabel.Enable = 'off';
            app.trialMMLabel.Visible = 'off';
            app.trialMMLabel.Text = 'MM';
            app.trialStartTimeSS = uieditfield(app.TrialTab, 'numeric');
            app.trialStartTimeSS.Limits = [0 Inf];
            app.trialStartTimeSS.RoundFractionalValues = 'on';
            app.trialStartTimeSS.ValueChangedFcn = createCallbackFcn(app, @trialStartTimeSSChanged, true);
            app.trialStartTimeSS.Enable = 'off';
            app.trialStartTimeSS.Visible = 'off';
            app.trialSSLabel = uilabel(app.TrialTab);
            app.trialSSLabel.Enable = 'off';
            app.trialSSLabel.Visible = 'off';
            app.trialSSLabel.Text = 'SS';
            app.trialStartTimess = uieditfield(app.TrialTab, 'numeric');
            app.trialStartTimess.Limits = [0 Inf];
            app.trialStartTimess.RoundFractionalValues = 'on';
            app.trialStartTimess.ValueChangedFcn = createCallbackFcn(app, @trialStartTimessChanged, true);
            app.trialStartTimess.Enable = 'off';
            app.trialStartTimess.Visible = 'off';
            app.trialSsLabel = uilabel(app.TrialTab);
            app.trialSsLabel.Enable = 'off';
            app.trialSsLabel.Visible = 'off';
            app.trialSsLabel.Text = 'ss';
            app.trialEndTimeHH = uieditfield(app.TrialTab, 'numeric');
            app.trialEndTimeHH.Limits = [0 Inf];
            app.trialEndTimeHH.RoundFractionalValues = 'on';
            app.trialEndTimeHH.ValueChangedFcn = createCallbackFcn(app, @trialEndTimeHHChanged, true);
            app.trialEndTimeHH.Enable = 'off';
            app.trialEndTimeHH.Visible = 'off';
            app.trialEndTimeMM = uieditfield(app.TrialTab, 'numeric');
            app.trialEndTimeMM.Limits = [0 Inf];
            app.trialEndTimeMM.RoundFractionalValues = 'on';
            app.trialEndTimeMM.ValueChangedFcn = createCallbackFcn(app, @trialEndTimeMMChanged, true);
            app.trialEndTimeMM.Enable = 'off';
            app.trialEndTimeMM.Visible = 'off';
            app.trialEndTimeSS = uieditfield(app.TrialTab, 'numeric');
            app.trialEndTimeSS.Limits = [0 Inf];
            app.trialEndTimeSS.RoundFractionalValues = 'on';
            app.trialEndTimeSS.ValueChangedFcn = createCallbackFcn(app, @trialEndTimeSSChanged, true);
            app.trialEndTimeSS.Enable = 'off';
            app.trialEndTimeSS.Visible = 'off';
            app.trialEndTimess = uieditfield(app.TrialTab, 'numeric');
            app.trialEndTimess.Limits = [0 Inf];
            app.trialEndTimess.RoundFractionalValues = 'on';
            app.trialEndTimess.ValueChangedFcn = createCallbackFcn(app, @trialEndTimessChanged, true);
            app.trialEndTimess.Enable = 'off';
            app.trialEndTimess.Visible = 'off';
            app.trialEndtimeLabel = uilabel(app.TrialTab);
            app.trialEndtimeLabel.Enable = 'off';
            app.trialEndtimeLabel.Text = 'End frame';
            app.trialRateLabel = uilabel(app.TrialTab);
            app.trialRateLabel.Text = 'Sample rate (Hz)';
            app.InputLabel = uilabel(app.TrialTab);
            app.InputLabel.Text = 'Input';
            app.trialFreqInText = uieditfield(app.TrialTab, 'text');
            app.trialFreqInText.ValueChangedFcn = createCallbackFcn(app, @trialFreqInTextfieldChanged, true);
            app.trialFreqOutText = uieditfield(app.TrialTab, 'text');
            app.trialFreqOutText.ValueChangedFcn = createCallbackFcn(app, @trailFreqOutTextfieldChanged, true);
            app.OutputLabel = uilabel(app.TrialTab);
            app.OutputLabel.Text = 'Output';
            app.trialLabel = uilabel(app.TrialTab);
            app.trialLabel.Text = 'Trial';
            app.trialDurationUnits = uidropdown(app.TrialTab);
            app.trialDurationUnits.Items = {'Frame', 'Time'};
            app.trialDurationUnits.ValueChangedFcn = createCallbackFcn(app, @trialDurationUnitsChanged, true);
            app.trialDurationUnits.Enable = 'off';
            app.trialDurationUnits.Value = 'Frame';
            app.trialStartFrameText = uieditfield(app.TrialTab, 'numeric');
            app.trialStartFrameText.Limits = [0 Inf];
            app.trialStartFrameText.RoundFractionalValues = 'on';
            app.trialStartFrameText.ValueChangedFcn = createCallbackFcn(app, @trialStartFrameTextChanged, true);
            app.trialStartFrameText.Enable = 'off';
            app.trialEndFrameText = uieditfield(app.TrialTab, 'numeric');
            app.trialEndFrameText.Limits = [0 Inf];
            app.trialEndFrameText.RoundFractionalValues = 'on';
            app.trialEndFrameText.ValueChangedFcn = createCallbackFcn(app, @trialEndFrameTextChanged, true);
            app.trialEndFrameText.Enable = 'off';
            app.defaultinputoutput1125HzLabel = uilabel(app.TrialTab);
            app.defaultinputoutput1125HzLabel.Text = '(default input/output = 1125Hz)';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'CalibrationTab';
            app.calibLabel = uilabel(app.CalibrationTab);
            app.calibLabel.Text = 'Calibration - static';
            app.calibRateLabel = uilabel(app.CalibrationTab);
            app.calibRateLabel.Text = 'Sample rate (Hz)';
            app.InputLabel_2 = uilabel(app.CalibrationTab);
            app.InputLabel_2.Text = 'Input';
            app.calibFreqInText = uieditfield(app.CalibrationTab, 'text');
            app.calibFreqInText.ValueChangedFcn = createCallbackFcn(app, @calibFreqInTextChanged, true);
            app.calibFreqOutText = uieditfield(app.CalibrationTab, 'text');
            app.calibFreqOutText.ValueChangedFcn = createCallbackFcn(app, @calibFreqOutTextChanged, true);
            app.OutputLabel_2 = uilabel(app.CalibrationTab);
            app.OutputLabel_2.Text = 'Output';
            app.calibDurationLabel = uilabel(app.CalibrationTab);
            app.calibDurationLabel.Text = 'Calibration duration';
            app.calibDurationRadio = uibuttongroup(app.CalibrationTab);
            app.calibDurationRadio.SelectionChangedFcn = createCallbackFcn(app, @calibDurationRadioSelectionChanged, true);
            app.calibEntireRadioButton = uiradiobutton(app.calibDurationRadio);
            app.calibEntireRadioButton.Text = 'Entire calibration';
            app.calibEntireRadioButton.Value = true;
            app.calibSelectRadioButton = uiradiobutton(app.calibDurationRadio);
            app.calibSelectRadioButton.Text = 'Select start/end timestamp';
            app.defaultfirst1sLabel = uilabel(app.calibDurationRadio);
            app.defaultfirst1sLabel.Text = '(default = first 1s)';
            app.calibStartLabel = uilabel(app.CalibrationTab);
            app.calibStartLabel.Enable = 'off';
            app.calibStartLabel.Text = 'Start frame';
            app.calibEndLabel = uilabel(app.CalibrationTab);
            app.calibEndLabel.Enable = 'off';
            app.calibEndLabel.Text = 'End frame';
            app.calibEndTimeHH = uieditfield(app.CalibrationTab, 'numeric');
            app.calibEndTimeHH.Limits = [0 Inf];
            app.calibEndTimeHH.RoundFractionalValues = 'on';
            app.calibEndTimeHH.ValueChangedFcn = createCallbackFcn(app, @calibEndTimeHHChanged, true);
            app.calibEndTimeHH.Enable = 'off';
            app.calibEndTimeHH.Visible = 'off';
            app.calibEndTimeMM = uieditfield(app.CalibrationTab, 'numeric');
            app.calibEndTimeMM.Limits = [0 Inf];
            app.calibEndTimeMM.RoundFractionalValues = 'on';
            app.calibEndTimeMM.ValueChangedFcn = createCallbackFcn(app, @calibEndTimeMMChanged, true);
            app.calibEndTimeMM.Enable = 'off';
            app.calibEndTimeMM.Visible = 'off';
            app.calibEndTimeSS = uieditfield(app.CalibrationTab, 'numeric');
            app.calibEndTimeSS.Limits = [0 Inf];
            app.calibEndTimeSS.RoundFractionalValues = 'on';
            app.calibEndTimeSS.ValueChangedFcn = createCallbackFcn(app, @calibEndTimeSSChanged, true);
            app.calibEndTimeSS.Enable = 'off';
            app.calibEndTimeSS.Visible = 'off';
            app.calibEndTimess = uieditfield(app.CalibrationTab, 'numeric');
            app.calibEndTimess.Limits = [0 Inf];
            app.calibEndTimess.RoundFractionalValues = 'on';
            app.calibEndTimess.ValueChangedFcn = createCallbackFcn(app, @calibEndTimeChanged, true);
            app.calibEndTimess.Enable = 'off';
            app.calibEndTimess.Visible = 'off';
            app.calibStartTimeHH = uieditfield(app.CalibrationTab, 'numeric');
            app.calibStartTimeHH.Limits = [0 Inf];
            app.calibStartTimeHH.RoundFractionalValues = 'on';
            app.calibStartTimeHH.ValueChangedFcn = createCallbackFcn(app, @calibStartTimeHHChanged, true);
            app.calibStartTimeHH.Enable = 'off';
            app.calibStartTimeHH.Visible = 'off';
            app.calibHHLabel = uilabel(app.CalibrationTab);
            app.calibHHLabel.Visible = 'off';
            app.calibHHLabel.Text = 'HH';
            app.calibStartTimeMM = uieditfield(app.CalibrationTab, 'numeric');
            app.calibStartTimeMM.Limits = [0 Inf];
            app.calibStartTimeMM.RoundFractionalValues = 'on';
            app.calibStartTimeMM.ValueChangedFcn = createCallbackFcn(app, @calibStartTimeMMChanged, true);
            app.calibStartTimeMM.Enable = 'off';
            app.calibStartTimeMM.Visible = 'off';
            app.calibMMLabel = uilabel(app.CalibrationTab);
            app.calibMMLabel.Visible = 'off';
            app.calibMMLabel.Text = 'MM';
            app.calibStartTimeSS = uieditfield(app.CalibrationTab, 'numeric');
            app.calibStartTimeSS.Limits = [0 Inf];
            app.calibStartTimeSS.RoundFractionalValues = 'on';
            app.calibStartTimeSS.ValueChangedFcn = createCallbackFcn(app, @calibStartTimeSSChanged, true);
            app.calibStartTimeSS.Enable = 'off';
            app.calibStartTimeSS.Visible = 'off';
            app.calibSSLabel = uilabel(app.CalibrationTab);
            app.calibSSLabel.Visible = 'off';
            app.calibSSLabel.Text = 'SS';
            app.calibStartTimess = uieditfield(app.CalibrationTab, 'numeric');
            app.calibStartTimess.Limits = [0 Inf];
            app.calibStartTimess.RoundFractionalValues = 'on';
            app.calibStartTimess.ValueChangedFcn = createCallbackFcn(app, @calibStartTimeChanged, true);
            app.calibStartTimess.Enable = 'off';
            app.calibStartTimess.Visible = 'off';
            app.calibSsLabel = uilabel(app.CalibrationTab);
            app.calibSsLabel.Visible = 'off';
            app.calibSsLabel.Text = 'ss';
            app.calibDurationUnits = uidropdown(app.CalibrationTab);
            app.calibDurationUnits.Items = {'Frame', 'Time'};
            app.calibDurationUnits.ValueChangedFcn = createCallbackFcn(app, @calibDurationUnitsChanged, true);
            app.calibDurationUnits.Enable = 'off';
            app.calibDurationUnits.Value = 'Frame';
            app.calibStartFrameText = uieditfield(app.CalibrationTab, 'numeric');
            app.calibStartFrameText.Limits = [0 Inf];
            app.calibStartFrameText.RoundFractionalValues = 'on';
            app.calibStartFrameText.ValueChangedFcn = createCallbackFcn(app, @calibStartFrameTextChanged, true);
            app.calibStartFrameText.Enable = 'off';
            app.calibEndFrameText = uieditfield(app.CalibrationTab, 'numeric');
            app.calibEndFrameText.Limits = [0 Inf];
            app.calibEndFrameText.RoundFractionalValues = 'on';
            app.calibEndFrameText.ValueChangedFcn = createCallbackFcn(app, @calibEndFrameameTextChanged, true);
            app.calibEndFrameText.Enable = 'off';
            app.defaultinputoutput1125HzLabel_2 = uilabel(app.CalibrationTab);
            app.defaultinputoutput1125HzLabel_2.Text = '(default input/output = 1125Hz)';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.tableOutputLabel = uilabel(app.ExportTab);
            app.tableOutputLabel.Text = 'Data table output parameters (column)';
            app.plotOutputLabel = uilabel(app.ExportTab);
            app.plotOutputLabel.Text = 'Plot output parameters';
            app.ankleBilateralCheck = uicheckbox(app.ExportTab);
            app.ankleBilateralCheck.ValueChangedFcn = createCallbackFcn(app, @ankleBilateralCheckValueChanged, true);
            app.ankleBilateralCheck.Text = 'Ankle bilateral';
            app.allUnilateralRCheck = uicheckbox(app.ExportTab);
            app.allUnilateralRCheck.ValueChangedFcn = createCallbackFcn(app, @allUnilateralRCheckValueChanged, true);
            app.allUnilateralRCheck.Text = 'All unilateral (R)';
            app.allUnilateralLCheck = uicheckbox(app.ExportTab);
            app.allUnilateralLCheck.ValueChangedFcn = createCallbackFcn(app, @allUnilateralLCheckValueChanged, true);
            app.allUnilateralLCheck.Text = 'All unilateral (L)';
            app.allBilateralCheck = uicheckbox(app.ExportTab);
            app.allBilateralCheck.ValueChangedFcn = createCallbackFcn(app, @allBilateralCheckValueChanged, true);
            app.allBilateralCheck.Text = 'All bilateral';
            app.hipUnilateralLCheck = uicheckbox(app.ExportTab);
            app.hipUnilateralLCheck.Text = 'Hip unilateral (L)';
            app.hipBilateralCheck = uicheckbox(app.ExportTab);
            app.hipBilateralCheck.ValueChangedFcn = createCallbackFcn(app, @hipBilateralCheckValueChanged, true);
            app.hipBilateralCheck.Text = 'Hip bilateral';
            app.ankleUnilateralRCheck = uicheckbox(app.ExportTab);
            app.ankleUnilateralRCheck.Text = 'Ankle unilateral (R)';
            app.ankleUnilateralLCheck = uicheckbox(app.ExportTab);
            app.ankleUnilateralLCheck.Text = 'Ankle unilateral (L)';
            app.kneeUnilateralRCheck = uicheckbox(app.ExportTab);
            app.kneeUnilateralRCheck.Text = 'Knee unilateral (R)';
            app.kneeUnilateralLCheck = uicheckbox(app.ExportTab);
            app.kneeUnilateralLCheck.Text = 'Knee unilateral (L)';
            app.kneeBilateralCheck = uicheckbox(app.ExportTab);
            app.kneeBilateralCheck.ValueChangedFcn = createCallbackFcn(app, @kneeBilateralCheckValueChanged, true);
            app.kneeBilateralCheck.Text = 'Knee bilateral';
            app.hipUnilateralRCheck = uicheckbox(app.ExportTab);
            app.hipUnilateralRCheck.Text = 'Hip unilateral (R)';
            app.dataOutputDirectoryLabel = uilabel(app.ExportTab);
            app.dataOutputDirectoryLabel.Text = 'Data output directory';
            app.dataOutputButton = uibutton(app.ExportTab, 'push');
            app.dataOutputButton.ButtonPushedFcn = createCallbackFcn(app, @dataOutputButtonPushed, true);
            app.dataOutputButton.Text = 'Select directory';
            app.dataOutputDirectoryField = uieditfield(app.ExportTab, 'text');
            app.dataOutputDirectoryField.Editable = 'off';
            app.plotOutputButton = uibutton(app.ExportTab, 'push');
            app.plotOutputButton.ButtonPushedFcn = createCallbackFcn(app, @plotOutputButtonPushed, true);
            app.plotOutputButton.Text = 'Select directory';
            app.plotOutputDirectoryField = uieditfield(app.ExportTab, 'text');
            app.plotOutputDirectoryField.Editable = 'off';
            app.plotOutputDirectoryLabel = uilabel(app.ExportTab);
            app.plotOutputDirectoryLabel.Text = 'Plot output directory';
            app.hipRDataCheck = uicheckbox(app.ExportTab);
            app.hipRDataCheck.Text = 'Hip angle (R)';
            app.hipLDataCheck = uicheckbox(app.ExportTab);
            app.hipLDataCheck.Text = 'Hip angle (L)';
            app.timeDataCheck = uicheckbox(app.ExportTab);
            app.timeDataCheck.Text = 'Timestamp';
            app.allDataCheck = uicheckbox(app.ExportTab);
            app.allDataCheck.ValueChangedFcn = createCallbackFcn(app, @allDataCheckValueChanged, true);
            app.allDataCheck.Text = 'All';
            app.ankleRDataCheck = uicheckbox(app.ExportTab);
            app.ankleRDataCheck.Text = 'Ankle angle (R)';
            app.ankleLDataCheck = uicheckbox(app.ExportTab);
            app.ankleLDataCheck.Text = 'Ankle angle (L)';
            app.kneeRDataCheck = uicheckbox(app.ExportTab);
            app.kneeRDataCheck.Text = 'Knee angle (R)';
            app.kneeLDataCheck = uicheckbox(app.ExportTab);
            app.kneeLDataCheck.Text = 'Knee angle (L)';
            app.exportLabel = uilabel(app.ExportTab);
            app.exportLabel.Text = 'Export';
            app.analysisLabel = uilabel(app.ExportTab);
            app.analysisLabel.Text = 'Analysis plane';
            app.sagittalCheck = uicheckbox(app.ExportTab);
            app.sagittalCheck.Text = 'Sagittal';
            app.allPlaneCheck = uicheckbox(app.ExportTab);
            app.allPlaneCheck.ValueChangedFcn = createCallbackFcn(app, @allPlaneCheckValueChanged, true);
            app.allPlaneCheck.Text = 'All';
            app.transverseCheck = uicheckbox(app.ExportTab);
            app.transverseCheck.Text = 'Transverse';
            app.frontalCheck = uicheckbox(app.ExportTab);
            app.frontalCheck.Text = 'Frontal';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filter';
            app.filterLabel = uilabel(app.FilteringTab);
            app.filterLabel.Text = 'Filtering';
            app.filterPropsLabel = uilabel(app.FilteringTab);
            app.filterPropsLabel.Text = 'Properties';
            app.FiltertypeIIRLabel = uilabel(app.FilteringTab);
            app.FiltertypeIIRLabel.Text = 'Filter type (IIR)';
            app.filterTypeDropDown = uidropdown(app.FilteringTab);
            app.filterTypeDropDown.Items = {'None', 'Bandpass', 'Bandstop', 'Highpass', 'Lowpass'};
            app.filterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @filterTypeDropDownValueChanged, true);
            app.filterTypeDropDown.Value = 'None';
            app.filterPassbandFreqVal = uieditfield(app.FilteringTab, 'text');
            app.filterPassbandFreqVal.Enable = 'off';
            app.filterPassbandRippleVal = uieditfield(app.FilteringTab, 'text');
            app.filterPassbandRippleVal.Enable = 'off';
            app.filterHalfPowFreq2Val = uieditfield(app.FilteringTab, 'text');
            app.filterHalfPowFreq2Val.Visible = 'off';
            app.filterHalfPowFreq1Val = uieditfield(app.FilteringTab, 'text');
            app.filterHalfPowFreq1Val.Visible = 'off';
            app.filterPassbandFreqLab = uilabel(app.FilteringTab);
            app.filterPassbandFreqLab.Enable = 'off';
            app.filterPassbandFreqLab.Text = 'Passband freq (Hz)';
            app.filterHalfPowFreq1Lab = uilabel(app.FilteringTab);
            app.filterHalfPowFreq1Lab.Visible = 'off';
            app.filterHalfPowFreq1Lab.Text = 'Half-power freq 1 (Hz)';
            app.filterHalfPowFreq2Lab = uilabel(app.FilteringTab);
            app.filterHalfPowFreq2Lab.Visible = 'off';
            app.filterHalfPowFreq2Lab.Text = 'Half-power freq 2 (Hz)';
            app.filterPassbandRippleLab = uilabel(app.FilteringTab);
            app.filterPassbandRippleLab.Enable = 'off';
            app.filterPassbandRippleLab.Text = 'Ripple freq (dB)';
            app.SelectfiltertypeandfiltefileLabel = uilabel(app.FilteringTab);
            app.SelectfiltertypeandfiltefileLabel.Visible = 'off';
            app.SelectfiltertypeandfiltefileLabel.Text = 'Select filter type and filte file';
            app.filterSelectField = uitextarea(app.FilteringTab);
            app.filterSelectField.Editable = 'off';
            app.filterSelectField.Visible = 'off';
            app.filterSelectButton = uibutton(app.FilteringTab, 'push');
            app.filterSelectButton.ButtonPushedFcn = createCallbackFcn(app, @filterSelectButtonPushed, true);
            app.filterSelectButton.Visible = 'off';
            app.filterSelectButton.Text = 'Select file';
            app.filterOrderVal = uieditfield(app.FilteringTab, 'text');
            app.filterOrderVal.Enable = 'off';
            app.filterOrderLab = uilabel(app.FilteringTab);
            app.filterOrderLab.Enable = 'off';
            app.filterOrderLab.Text = 'Order';
            app.filterClearButton = uibutton(app.FilteringTab, 'push');
            app.filterClearButton.ButtonPushedFcn = createCallbackFcn(app, @filterClearButtonPushed, true);
            app.filterClearButton.Visible = 'off';
            app.filterClearButton.Text = 'Clear';
            app.filterTypeRadioGroup = uibuttongroup(app.FilteringTab);
            app.filterTypeRadioGroup.Visible = 'off';
            app.FIRButton = uiradiobutton(app.filterTypeRadioGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.IIRButton = uiradiobutton(app.filterTypeRadioGroup);
            app.IIRButton.Text = 'IIR';
            app.SecondorderIIRButton = uiradiobutton(app.filterTypeRadioGroup);
            app.SecondorderIIRButton.Text = 'Second-order IIR';
            app.filterPassbandDescText = uilabel(app.FilteringTab);
            app.filterPassbandDescText.Text = 'Also know as cut-off frequency.';
            app.filterPassbandDescLab = uilabel(app.FilteringTab);
            app.filterPassbandDescLab.Text = 'Passband freq';
            app.filterRippleDescText = uilabel(app.FilteringTab);
            app.filterRippleDescText.Text = 'The allowable deviation from flatness of the passband. Larger values will lead to shorter transition widths.';
            app.filterRippleDescLab = uilabel(app.FilteringTab);
            app.filterRippleDescLab.Text = 'Ripple freq';
            app.filterHalfpower1DescText = uilabel(app.FilteringTab);
            app.filterHalfpower1DescText.Visible = 'off';
            app.filterHalfpower1DescText.Text = {'Frequency at the lower end of the passband (bandpass filter). Frequency at the lower end of the stopband '; '(bandstop filter).'};
            app.filterHalfpower1DescLab = uilabel(app.FilteringTab);
            app.filterHalfpower1DescLab.Visible = 'off';
            app.filterHalfpower1DescLab.Text = 'Half-power freq 1';
            app.filterHalfpower2DescText = uilabel(app.FilteringTab);
            app.filterHalfpower2DescText.Visible = 'off';
            app.filterHalfpower2DescText.Text = {'Frequency at the upper end of the passband (bandpass filter). Frequency at the upper end of the stopband '; '(bandstop filter).'};
            app.filterHalfpower2DescLab = uilabel(app.FilteringTab);
            app.filterHalfpower2DescLab.Visible = 'off';
            app.filterHalfpower2DescLab.Text = 'Half-power freq 2';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.runButton = uibutton(app.RunTab, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'Run model!';
            app.UIAxes = uiaxes(app.RunTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.runLabel = uilabel(app.RunTab);
            app.runLabel.Text = 'Run model';
            app.completionLabel = uilabel(app.RunTab);
            app.completionLabel.Text = '';
            app.percentageCompleteLabel = uilabel(app.RunTab);
            app.percentageCompleteLabel.Visible = 'off';
            app.percentageCompleteLabel.Text = '0%';
            app.continueButton = uibutton(app.RunTab, 'push');
            app.continueButton.ButtonPushedFcn = createCallbackFcn(app, @continueButtonPushed, true);
            app.continueButton.Visible = 'off';
            app.continueButton.Text = 'Continue';
            app.PlotTabGroup = uitabgroup(app.RunTab);
            app.ManualPlots = uitab(app.PlotTabGroup);
            app.ManualPlots.Title = 'ManualPlots';
            app.StaticAxes = uiaxes(app.ManualPlots);
            title(app.StaticAxes, 'Static calibration')
            xlabel(app.StaticAxes, '')
            ylabel(app.StaticAxes, '')
            app.StaticAxes.Visible = 'off';
            app.MotionAxes = uiaxes(app.ManualPlots);
            title(app.MotionAxes, 'Motion calibration')
            xlabel(app.MotionAxes, '')
            ylabel(app.MotionAxes, '')
            app.MotionAxes.XTick = [];
            app.MotionAxes.XTickLabel = '';
            app.MotionAxes.Visible = 'off';
            app.TrialAxes2 = uiaxes(app.ManualPlots);
            title(app.TrialAxes2, 'Trial')
            xlabel(app.TrialAxes2, '')
            ylabel(app.TrialAxes2, '')
            app.TrialAxes2.Visible = 'off';
            app.AutoPlots = uitab(app.PlotTabGroup);
            app.AutoPlots.Title = 'AutoPlots';
            app.TrialAxes = uiaxes(app.AutoPlots);
            title(app.TrialAxes, 'Trial')
            xlabel(app.TrialAxes, '')
            ylabel(app.TrialAxes, '')
            app.TrialAxes.Visible = 'off';
            app.Label = uilabel(app.RunTab);
            app.Label.Text = ' ';
            app.UserguideTab = uitab(app.TabGroup);
            app.UserguideTab.Title = 'UserGuide';
            app.userLabel = uilabel(app.UserguideTab);
            app.userLabel.Text = 'User guide';
            app.FilenamingDescriptionLabel = uilabel(app.UserguideTab);
            app.FilenamingDescriptionLabel.Text = 'File naming conventions must adhere to the following:';
            app.TrialdataLabel = uilabel(app.UserguideTab);
            app.TrialdataLabel.Text = 'Trial data:';
            app.StaticcalibrationdataLabel = uilabel(app.UserguideTab);
            app.StaticcalibrationdataLabel.Text = 'Static calibration data:';
            app.MagneticcalibrationdataLabel = uilabel(app.UserguideTab);
            app.MagneticcalibrationdataLabel.Text = 'Magnetic calibration data:';
            app.exampleDescriptionLabel = uilabel(app.UserguideTab);
            app.exampleDescriptionLabel.Text = {'Any combination of values <exercise>, <participant>, <trial> and <date> forming a unique file name '; 'may be used for a given dataset. '};
            app.exampleDescriptionLabelTwo = uilabel(app.UserguideTab);
            app.exampleDescriptionLabelTwo.Text = {'An example dataset may be as follows:'; './data/squat_P01_T01_220828.csv'; './data/squat_P01_T02_220828.csv'; './data/cmj_P01_T01_220828.csv'; './data/cmj_P01_T02_220828.csv'; './data/static_P01_220828.csv'; './data/mag_P01_220828.csv'; ''};
            app.mag_participant_datecsvLabel = uilabel(app.UserguideTab);
            app.mag_participant_datecsvLabel.Text = 'mag_<participant>_<date>.csv';
            app.static_participant_datecsvLabel = uilabel(app.UserguideTab);
            app.static_participant_datecsvLabel.Text = 'static_<participant>_<date>.csv';
            app.exercise_participant_trial_datecsvLabel = uilabel(app.UserguideTab);
            app.exercise_participant_trial_datecsvLabel.Text = '<exercise>_<participant>_<trial>_<date>.csv';
            app.ExamplesLabel = uilabel(app.UserguideTab);
            app.ExamplesLabel.Text = 'Examples:';
            app.viconNamingButton = uibutton(app.UserguideTab, 'push');
            app.viconNamingButton.ButtonPushedFcn = createCallbackFcn(app, @viconNamingButtonPushed, true);
            app.viconNamingButton.Text = 'Vicon';
            app.namingConvButton = uibutton(app.UserguideTab, 'push');
            app.namingConvButton.ButtonPushedFcn = createCallbackFcn(app, @namingConvButtonPushed, true);
            app.namingConvButton.Text = 'File naming';
            app.setupMenuButton_4 = uibutton(app.UserguideTab, 'push');
            app.setupMenuButton_4.ButtonPushedFcn = createCallbackFcn(app, @setupMenuButton_4Pushed, true);
            app.setupMenuButton_4.Text = 'Sensors';
            app.ViconNameDescription = uilabel(app.UserguideTab);
            app.ViconNameDescription.Visible = 'off';
            app.ViconNameDescription.Text = {'Name sensors in vicon using variables to describe the limb for which it is attached.'; 'How a sensor is named in Vicon Nexus, will reflect how a sensor appears in this app.'};
            app.viconNamesA = uiimage(app.UserguideTab);
            app.viconNamesA.Visible = 'off';
            app.viconNamesB = uiimage(app.UserguideTab);
            app.viconNamesB.Visible = 'off';
            app.sensorDescriptionLabel = uilabel(app.UserguideTab);
            app.sensorDescriptionLabel.Visible = 'off';
            app.sensorDescriptionLabel.Text = 'Recommended sensor placement is as found in the below text.';
            app.sensorLocationImage = uiimage(app.UserguideTab);
            app.sensorLocationImage.Visible = 'off';
            app.sensorLocations = uilabel(app.UserguideTab);
            app.sensorLocations.Visible = 'off';
            app.sensorLocations.Text = {'Pelvis - midway between the right and left posterior superior '; 'iliac spine.'; ''; 'Thigh - Approximately 150mm proximal to the lateral epicondyle '; 'of the femur.'; ''; 'Shank - Aproximately 100 mm distal to the lateral tibial condyle.'; ''; 'Foot - fway between the lateral malleoli and the base of the foot.'};
            app.sensorDescriptionLabel2 = uilabel(app.UserguideTab);
            app.sensorDescriptionLabel2.Visible = 'off';
            app.sensorDescriptionLabel2.Text = {''; 'Hindle, B.R.; Keogh, J.W.L.; Lorimer, A.V. Validation of Spatiotemporal and Kinematic Measures in '; 'Functional Exercises Using a Minimal Modeling Inertial Sensor Methodology. '; 'Sensors 2020, 20, 4586. https://doi.org/10.3390/s20164586'};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.settingsLabel = uilabel(app.SettingsTab);
            app.settingsLabel.Text = 'Settings';
            app.settingsAutoLabel = uilabel(app.SettingsTab);
            app.settingsAutoLabel.Text = 'Processing progression method';
            app.settingsAutoRadio = uibuttongroup(app.SettingsTab);
            app.settingsAutoRadio.SelectionChangedFcn = createCallbackFcn(app, @settingsAutoRadioSelectionChanged, true);
            app.settingsAuto = uiradiobutton(app.settingsAutoRadio);
            app.settingsAuto.Text = 'Auto';
            app.settingsAuto.Value = true;
            app.settingsManual = uiradiobutton(app.settingsAutoRadio);
            app.settingsManual.Text = 'Manual';
            app.settingsAutoDescription = uilabel(app.SettingsTab);
            app.settingsAutoDescription.Text = {'Setting this to manual will require you to hit a "Continue" button between each joint analysis of each trial.'; 'Additional diagnostic plots will be displayed for the user to confirm the quality of data for each trial.'};
            app.diagnosticPlotLabel = uilabel(app.SettingsTab);
            app.diagnosticPlotLabel.Text = 'Save diagnostic plots';
            app.diagnosticPlotDescription = uilabel(app.SettingsTab);
            app.diagnosticPlotDescription.Text = {'Choose to save diagnostic plots for later reference when using both "Auto" or "Manual" progression'; 'methods.'};
            app.SaveDiagnosticPlotCheckBox = uicheckbox(app.SettingsTab);
            app.SaveDiagnosticPlotCheckBox.Text = '';
            app.SideMenuButtonGroup = uibuttongroup(app.UIFigure);
            app.setupMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.setupMenuButton.ButtonPushedFcn = createCallbackFcn(app, @setupMenuButtonPushed, true);
            app.setupMenuButton.Text = 'Setup';
            app.trialMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.trialMenuButton.ButtonPushedFcn = createCallbackFcn(app, @trialMenuButtonPushed, true);
            app.trialMenuButton.Text = 'Trial';
            app.userGuideMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.userGuideMenuButton.ButtonPushedFcn = createCallbackFcn(app, @userGuideMenuButtonPushed, true);
            app.userGuideMenuButton.Text = 'User guide';
            app.runMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.runMenuButton.ButtonPushedFcn = createCallbackFcn(app, @runMenuButtonPushed, true);
            app.runMenuButton.Text = 'Run model';
            app.exportMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.exportMenuButton.ButtonPushedFcn = createCallbackFcn(app, @exportMenuButtonPushed, true);
            app.exportMenuButton.Text = 'Export';
            app.calibrationMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.calibrationMenuButton.ButtonPushedFcn = createCallbackFcn(app, @calibStatMenuButtonPushed, true);
            app.calibrationMenuButton.Text = 'Calibration';
            app.sensorsMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.sensorsMenuButton.ButtonPushedFcn = createCallbackFcn(app, @sensorsMenuButtonPushed, true);
            app.sensorsMenuButton.Text = 'Sensors';
            app.settingsButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Text = 'Settings';
            app.filterMenuButton = uibutton(app.SideMenuButtonGroup, 'push');
            app.filterMenuButton.ButtonPushedFcn = createCallbackFcn(app, @filterMenuButtonPushed, true);
            app.filterMenuButton.Text = 'Filtering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mqAppTabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end